classdef Nachbearbeitung_OptimalControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GenerateButton        matlab.ui.control.Button
        XTranslatorischLabel  matlab.ui.control.Label
        UndoButton            matlab.ui.control.Button
        RedoButton            matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button                matlab.ui.control.Button
        UIAxes_4              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SelectedIndex (1,1) = nan; %Used to determine if a line of a graph has been clicked and which point has been clicked
        Time % FROM EMI FILE: Time points in the graph
        XTrans % X - Translatorisch
        YTrans % Y - Translatorisch
        ZTrans % Z - Translatorisch
        XRot % X - Rotatorisch
        YRot % Y - Rotatorisch
        ZRot % Z - Rotatorisch
        eighthRow
        ninthRow
        XTransStart % X - Translatorisch am Anfang
        YTransStart % Y - Translatorisch am Anfang
        ZTransStart % Z - Translatorisch am Anfang
        XRotStart % X - Rotatorisch am Anfang
        YRotStart % Y - Rotatorisch am Anfang 
        ZRotStart % Z - Rotatorisch am Anfang
        indexPlot % Used to determine on which category we are on ( XTrans or YTrans and etc)
        distanceValues % Distance between each time point from the property "Time"
        Linea % Plot of the "Weg" Graph
        Linea2 % Plot of the "Geschwindigkeit" Graph
        Linea3 % Plot of the "Beschleuinigung" Graph
        Linea4 % Plot of the "Ruck" Graph
        ActualAxes = 0; % Used to determine at which graph we are clicking at the moment
        RecentPointsX; % Position of the point that was changed in X
        RecentPointsY; % Position of the point that was changed in Y
        RecentAxes; % Recent Graph that was clicked on
        RecentIndex; % Recent Index of the point in the Dataset of the graph
        countUndo = 0; % Number of Undos possible or Number of times that a graph was changed
        lastPointX; % Used to determine the first position the point was in (X)
        lastPointY; % Used to determine the first position the point was in (Y)
        lastIndex; % Used to determine the index of that point in the Dataset
        boolLastPoint = true; % Used to make sure the first Point is grabbed
        headerEMI;
    end
    
    methods (Access = private)
        
        function ActivateMoveMode(app, event) % Callback: Graph was clicked and the index of the point is calculated
            if isnan(app.SelectedIndex)
                [~,app.SelectedIndex] = min(...
                   (event.Source.XData-event.IntersectionPoint(1)).^2+...
                   (event.Source.YData-event.IntersectionPoint(2)).^2); 
            end
            app.ActualAxes = 0;
        end

        function ActivateMoveMode2(app, event)
            if isnan(app.SelectedIndex)
                [~,app.SelectedIndex] = min(...
                   (event.Source.XData-event.IntersectionPoint(1)).^2+...
                   (event.Source.YData-event.IntersectionPoint(2)).^2); 
            end
            app.ActualAxes = 1;
        end

        function ActivateMoveMode3(app, event)
            if isnan(app.SelectedIndex)
                [~,app.SelectedIndex] = min(...
                   (event.Source.XData-event.IntersectionPoint(1)).^2+...
                   (event.Source.YData-event.IntersectionPoint(2)).^2); 
            end
            app.ActualAxes = 2;
        end

        function ActivateMoveMode4(app, event)
            if isnan(app.SelectedIndex)
                [~,app.SelectedIndex] = min(...
                   (event.Source.XData-event.IntersectionPoint(1)).^2+...
                   (event.Source.YData-event.IntersectionPoint(2)).^2); 
            end
            app.ActualAxes = 3;
        end
        
        
    end
    
    methods (Access = public)


        function resetValues(app) % Reset the Values needed for Redo and Undo
            app.countUndo = 0;
            app.lastPointX = nan;
            app.lastPointY = nan;
            app.lastIndex = nan;
            app.boolLastPoint = true;
            app.RecentPointsX = [];
            app.RecentPointsY = [];
            app.RecentAxes = [];
            app.RecentIndex = [];
        end
        
        function resetViewPlot(app)
            resetplotview(app.UIAxes);
            resetplotview(app.UIAxes_2);
            resetplotview(app.UIAxes_3);
            resetplotview(app.UIAxes_4);
        end
        
        function aufleitung(app, actualplot, actualAxes)

            switch actualAxes

                        case 0
                            
                            
                            switch actualplot
                                case 0

                                app.XTrans(app.lastIndex) = app.Linea.YData(app.lastIndex);    
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XTrans,app.distanceValues,app.Time,false);
                                case 1
                                    app.YTrans(app.lastIndex) = app.Linea.YData(app.lastIndex);    
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YTrans,app.distanceValues,app.Time,false);
                                
                                case 2
                                    app.ZTrans(app.lastIndex) = app.Linea.YData(app.lastIndex);    
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZTrans,app.distanceValues,app.Time,false);
                                
                                case 3
                                
                                    app.XRot(app.lastIndex) = app.Linea.YData(app.lastIndex);    
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XRot,app.distanceValues,app.Time,false);
                                case 4
                                    app.YRot(app.lastIndex) = app.Linea.YData(app.lastIndex);    
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YRot,app.distanceValues,app.Time,false);
                                
                                case 5
                                    app.ZRot(app.lastIndex) = app.Linea.YData(app.lastIndex);    
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZRot,app.distanceValues,app.Time,false);


                            end

                            app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                            app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                            app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                            app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                            app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                            app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);

                        case 1

                            C = app.Linea.YData(1);
                            coefficientsFunc = polyfit(app.Linea2.XData,app.Linea2.YData,4);
                            
                            switch actualplot

                                case 0

                                for i = 1:length(app.XTrans)
                                
                                app.XTrans(i) =  ((coefficientsFunc(1)*(app.Time(i)^5))/5) +((coefficientsFunc(2)*(app.Time(i)^4)/4)) +((coefficientsFunc(3)*(app.Time(i)^3)/3))+((coefficientsFunc(4)*(app.Time(i)^2)/2))+coefficientsFunc(5)*app.Time(i) + C;
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XTrans,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.XTrans,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                                
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                                case 1

                                for i = 1:length(app.YTrans)
                                
                                app.YTrans(i) =  ((coefficientsFunc(1)*(app.Time(i)^5))/5) +((coefficientsFunc(2)*(app.Time(i)^4)/4)) +((coefficientsFunc(3)*(app.Time(i)^3)/3))+((coefficientsFunc(4)*(app.Time(i)^2)/2))+coefficientsFunc(5)*app.Time(i) + C;
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YTrans,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.YTrans,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);

                                case 2

                                for i = 1:length(app.ZTrans)
                                
                                app.ZTrans(i) =  ((coefficientsFunc(1)*(app.Time(i)^5))/5) +((coefficientsFunc(2)*(app.Time(i)^4)/4)) +((coefficientsFunc(3)*(app.Time(i)^3)/3))+((coefficientsFunc(4)*(app.Time(i)^2)/2))+coefficientsFunc(5)*app.Time(i) + C;
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZTrans,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time,app.ZTrans,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                                case 3

                                for i = 1:length(app.XRot)
                                
                                app.XRot(i) =  ((coefficientsFunc(1)*(app.Time(i)^5))/5) +((coefficientsFunc(2)*(app.Time(i)^4)/4)) +((coefficientsFunc(3)*(app.Time(i)^3)/3))+((coefficientsFunc(4)*(app.Time(i)^2)/2))+coefficientsFunc(5)*app.Time(i) + C;
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XRot,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.XRot,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                                case 4

                                for i = 1:length(app.YRot)
                                
                                app.YRot(i) =  ((coefficientsFunc(1)*(app.Time(i)^5))/5) +((coefficientsFunc(2)*(app.Time(i)^4)/4)) +((coefficientsFunc(3)*(app.Time(i)^3)/3))+((coefficientsFunc(4)*(app.Time(i)^2)/2))+coefficientsFunc(5)*app.Time(i) + C;
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YRot,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.YRot,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                                case 5

                                for i = 1:length(app.ZRot)
                                
                                app.ZRot(i) =  ((coefficientsFunc(1)*(app.Time(i)^5))/5) +((coefficientsFunc(2)*(app.Time(i)^4)/4)) +((coefficientsFunc(3)*(app.Time(i)^3)/3))+((coefficientsFunc(4)*(app.Time(i)^2)/2))+coefficientsFunc(5)*app.Time(i) + C;
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZRot,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.ZRot,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true); 
    

                            end


                        case 2
                            C = app.Linea.YData(1);
                            coefficientsFunc = polyfit(app.Linea3.XData,app.Linea3.YData,4);

                            switch actualplot

                                case 0

                                for i = 1:length(app.XTrans)
                                
                                app.XTrans(i) =  ((coefficientsFunc(1)*(app.Time(i)^6))/30) +((coefficientsFunc(2)*(app.Time(i)^5))/20) +((coefficientsFunc(3)*(app.Time(i)^4))/12)+((coefficientsFunc(4)*(app.Time(i)^3))/6)+((coefficientsFunc(5)*app.Time(i)^2)/2) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XTrans,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.XTrans,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);

                                case 1

                                for i = 1:length(app.YTrans)
                                
                                app.YTrans(i) =  ((coefficientsFunc(1)*(app.Time(i)^6))/30) +((coefficientsFunc(2)*(app.Time(i)^5))/20) +((coefficientsFunc(3)*(app.Time(i)^4))/12)+((coefficientsFunc(4)*(app.Time(i)^3))/6)+((coefficientsFunc(5)*app.Time(i)^2)/2) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YTrans,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.YTrans,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);

                                case 2

                                for i = 1:length(app.ZTrans)
                                
                                app.ZTrans(i) = ((coefficientsFunc(1)*(app.Time(i)^6))/30) +((coefficientsFunc(2)*(app.Time(i)^5))/20) +((coefficientsFunc(3)*(app.Time(i)^4))/12)+((coefficientsFunc(4)*(app.Time(i)^3))/6)+((coefficientsFunc(5)*app.Time(i)^2)/2) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZTrans,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time,app.ZTrans,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);

                                case 3

                                for i = 1:length(app.XRot)
                                
                                app.XRot(i) =  ((coefficientsFunc(1)*(app.Time(i)^6))/30) +((coefficientsFunc(2)*(app.Time(i)^5))/20) +((coefficientsFunc(3)*(app.Time(i)^4))/12)+((coefficientsFunc(4)*(app.Time(i)^3))/6)+((coefficientsFunc(5)*app.Time(i)^2)/2) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XRot,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time,app.XRot,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);

                                case 4

                                for i = 1:length(app.YRot)
                                
                                app.YRot(i) =  ((coefficientsFunc(1)*(app.Time(i)^6))/30) +((coefficientsFunc(2)*(app.Time(i)^5))/20) +((coefficientsFunc(3)*(app.Time(i)^4))/12)+((coefficientsFunc(4)*(app.Time(i)^3))/6)+((coefficientsFunc(5)*app.Time(i)^2)/2) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YRot,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time,app.YRot,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);

                                case 5

                                for i = 1:length(app.ZRot)
                                
                                app.ZRot(i) =  ((coefficientsFunc(1)*(app.Time(i)^6))/30) +((coefficientsFunc(2)*(app.Time(i)^5))/20) +((coefficientsFunc(3)*(app.Time(i)^4))/12)+((coefficientsFunc(4)*(app.Time(i)^3))/6)+((coefficientsFunc(5)*app.Time(i)^2)/2) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZRot,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.ZRot,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                                app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true); 
    

                            end


                        case 3

                            C = app.Linea.YData(1);
                            coefficientsFunc = polyfit(app.Linea4.XData,app.Linea4.YData,4);

                            switch actualplot

                                case 0

                                for i = 1:length(app.XTrans)
                                
                                app.XTrans(i) =  ((coefficientsFunc(1)*(app.Time(i)^7))/210) +((coefficientsFunc(2)*(app.Time(i)^6))/120) +((coefficientsFunc(3)*(app.Time(i)^5))/60)+((coefficientsFunc(4)*(app.Time(i)^4))/24)+((coefficientsFunc(5)*app.Time(i)^3)/6) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XTrans,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.XTrans,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);

                                case 1

                                for i = 1:length(app.YTrans)
                                
                                app.YTrans(i) =  ((coefficientsFunc(1)*(app.Time(i)^7))/210) +((coefficientsFunc(2)*(app.Time(i)^6))/120) +((coefficientsFunc(3)*(app.Time(i)^5))/60)+((coefficientsFunc(4)*(app.Time(i)^4))/24)+((coefficientsFunc(5)*app.Time(i)^3)/6) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YTrans,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.YTrans,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);

                                case 2

                                for i = 1:length(app.ZTrans)
                                
                                app.ZTrans(i) = ((coefficientsFunc(1)*(app.Time(i)^7))/210) +((coefficientsFunc(2)*(app.Time(i)^6))/120) +((coefficientsFunc(3)*(app.Time(i)^5))/60)+((coefficientsFunc(4)*(app.Time(i)^4))/24)+((coefficientsFunc(5)*app.Time(i)^3)/6) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZTrans,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time,app.ZTrans,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);

                                case 3

                                for i = 1:length(app.XRot)
                                
                                app.XRot(i) =  ((coefficientsFunc(1)*(app.Time(i)^7))/210) +((coefficientsFunc(2)*(app.Time(i)^6))/120) +((coefficientsFunc(3)*(app.Time(i)^5))/60)+((coefficientsFunc(4)*(app.Time(i)^4))/24)+((coefficientsFunc(5)*app.Time(i)^3)/6) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XRot,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.XRot,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);

                                case 4

                                for i = 1:length(app.YRot)
                                
                                app.YRot(i) =  ((coefficientsFunc(1)*(app.Time(i)^7))/210) +((coefficientsFunc(2)*(app.Time(i)^6))/120) +((coefficientsFunc(3)*(app.Time(i)^5))/60)+((coefficientsFunc(4)*(app.Time(i)^4))/24)+((coefficientsFunc(5)*app.Time(i)^3)/6) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YRot,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.YRot,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);

                                case 5

                                for i = 1:length(app.ZRot)
                                
                                app.ZRot(i) =  ((coefficientsFunc(1)*(app.Time(i)^7))/210) +((coefficientsFunc(2)*(app.Time(i)^6))/120) +((coefficientsFunc(3)*(app.Time(i)^5))/60)+((coefficientsFunc(4)*(app.Time(i)^4))/24)+((coefficientsFunc(5)*app.Time(i)^3)/6) + C; 
                                end
                            
                                [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZRot,app.distanceValues,app.Time,false);
                                app.Linea = plot(app.UIAxes,app.Time, app.ZRot,'-o');
                                app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);    
                                app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                                app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                                app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                                app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true); 
    

                            end


                    end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataEMI)
           
           
            %Index for Plot 
            app.indexPlot = 0;
            
            %Open the EMI FILE and setup variables
            fid = fopen(dataEMI,'rt');
            loadData = false;
            deleteLines = 0;
            countRow = 1;
            countColumn = 1;
            dataArray = zeros(0,0);
            
            count = 0;
            while true
                if ~ischar( fgetl(fid) ); break; end % Get number of Rows in EMI FILE
                    count = count + 1;
            end
            fclose(fid);

            data = fileread(dataEMI);
            data = regexprep(data,' +',' '); % Format the data to be readable
            fid = fopen(dataEMI,'rt'); % open the EMI FILE to read it 

            while true
                thisline = fgetl(fid); % Read Line by Line
                
                if(contains(thisline, "END")) 
                    break;
                end

                if (loadData) %IF Header has been removed proceed to load the data into a big array
                    templine = split(thisline);             
                    templine(1) = [];
                    while true
                        values = str2double(templine);
                        format longg
                        dataArray(countRow, countColumn) = values(countColumn);
                        countColumn = countColumn + 1;
                        if(countColumn > 9)
                         countColumn = 1;
                         countRow = countRow + 1;
                         break;
                        end
                    end
                end
                
                if(~loadData)
                    if (contains(thisline, "RECORDS")) %Remove Header and determine the size of array

                        deleteLines = deleteLines + 2;

                        loadData = true;
                    
                        dataArray = zeros(count - deleteLines, 9);
                    	
                        app.headerEMI = strvcat(app.headerEMI,thisline);
                       
                    else
                        deleteLines = deleteLines + 1;
                        app.headerEMI = strvcat(app.headerEMI,thisline);
                    end
                end


            end
            fclose(fid);
            
            propertyIndex = 0;
            tempArray = zeros(1,count - deleteLines);

            for i = 1:9 % Transfer the values of each Column into a single array

                for j = 1:count - deleteLines
                    tempArray(j) = dataArray(j,i);
                end

                switch propertyIndex
                    case 0
                        app.Time = tempArray;
                    case 1
                        app.XTrans = tempArray;
                        app.XTransStart = tempArray;
                    case 2
                        app.YTrans = tempArray;
                        app.YTransStart = tempArray;
                    case 3
                        app.ZTrans = tempArray;
                        app.ZTransStart = tempArray;
                    case 4
                        app.XRot = tempArray;
                        app.XRotStart = tempArray;
                    case 5
                        app.YRot = tempArray;
                        app.YRotStart = tempArray;
                    case 6
                        app.ZRot = tempArray;
                        app.ZRotStart = tempArray;
                    case 7
                        app.eighthRow = tempArray;
                        
                    case 8
                        app.ninthRow = tempArray;
                        
                end

                propertyIndex = propertyIndex + 1;
                tempArray = zeros(1,count - deleteLines);
            end
           
            %Plot the "Weg" Graph
            app.Linea = line(app.UIAxes,app.Time,app.XTrans,'Marker','o');
            app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
           

            %Distance between Time values
            app.distanceValues = zeros(1,25);
            for i = 1:25 
                    app.distanceValues(i) = app.Time(i+1) - app.Time(i);
            end
            
           
            %Calculate the other graphs and plot them
            [sp,geschwindigkeit,beschleunigung,ruck,time,passendeStelle] = spline(app.XTrans,app.distanceValues,app.Time,false);

            app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
            app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
            app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
            app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
            app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
            app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~isnan(app.SelectedIndex)
                p = event.Source.CurrentPoint;
                x = p(1);
                y = p(2);
                switch app.ActualAxes

                    case 0
                    x1 = app.UIAxes.InnerPosition(1);
                    x2 = sum(app.UIAxes.InnerPosition([1 3]));
                    y1 = app.UIAxes.InnerPosition(2);
                    y2 = sum(app.UIAxes.InnerPosition([2 4]));
                    if x>x1 && x<x2 && y>y1 && y<y2
                        xl = app.UIAxes.XLim;
                        yl = app.UIAxes.YLim;
                        xax = xl(1)+(xl(2)-xl(1))*(x-x1)/(x2-x1);
                        yax = yl(1)+(yl(2)-yl(1))*(y-y1)/(y2-y1);
                        
                        if(app.boolLastPoint)
                        app.lastPointX = app.Linea.XData(app.SelectedIndex); %Save Data for Undo and Redo
                        app.lastPointY = app.Linea.YData(app.SelectedIndex);
                        app.boolLastPoint = false;
                        end

                        app.lastIndex = app.SelectedIndex;

                        app.Linea.XData(app.SelectedIndex) = xax;
                        app.Linea.YData(app.SelectedIndex) = yax;
%                     line(app.UIAxes,xax,yax,'marker','o');
                        
                    end

                    case 1
                    x1 = app.UIAxes_2.InnerPosition(1);
                    x2 = sum(app.UIAxes_2.InnerPosition([1 3]));
                    y1 = app.UIAxes_2.InnerPosition(2);
                    y2 = sum(app.UIAxes_2.InnerPosition([2 4]));
                    if x>x1 && x<x2 && y>y1 && y<y2
                        xl = app.UIAxes_2.XLim;
                        yl = app.UIAxes_2.YLim;
                        xax = xl(1)+(xl(2)-xl(1))*(x-x1)/(x2-x1);
                        yax = yl(1)+(yl(2)-yl(1))*(y-y1)/(y2-y1);
                    
                        if(app.boolLastPoint)
                        app.lastPointX = app.Linea2.XData(app.SelectedIndex); %Save Data for Undo and Redo
                        app.lastPointY = app.Linea2.YData(app.SelectedIndex);
                        app.boolLastPoint = false;
                        end

                        app.lastIndex = app.SelectedIndex;

                        app.Linea2.XData(app.SelectedIndex) = xax;
   
                        app.Linea2.YData(app.SelectedIndex) = yax;
%                     line(app.UIAxes,xax,yax,'marker','o');
                        
                    end

                    case 2
                    x1 = app.UIAxes_3.InnerPosition(1);
                    x2 = sum(app.UIAxes_3.InnerPosition([1 3]));
                    y1 = app.UIAxes_3.InnerPosition(2);
                    y2 = sum(app.UIAxes_3.InnerPosition([2 4]));
                    if x>x1 && x<x2 && y>y1 && y<y2
                        xl = app.UIAxes_3.XLim;
                        yl = app.UIAxes_3.YLim;
                        xax = xl(1)+(xl(2)-xl(1))*(x-x1)/(x2-x1);
                        yax = yl(1)+(yl(2)-yl(1))*(y-y1)/(y2-y1);
                        
                        if(app.boolLastPoint)
                        app.lastPointX = app.Linea3.XData(app.SelectedIndex); %Save Data for Undo and Redo
                        app.lastPointY = app.Linea3.YData(app.SelectedIndex);
                        app.boolLastPoint = false;
                        end

                        app.lastIndex = app.SelectedIndex;

                        app.Linea3.XData(app.SelectedIndex) = xax;
                        app.Linea3.YData(app.SelectedIndex) = yax;
%                     line(app.UIAxes,xax,yax,'marker','o');
                        
                    end

                    case 3
                    x1 = app.UIAxes_4.InnerPosition(1);
                    x2 = sum(app.UIAxes_4.InnerPosition([1 3]));
                    y1 = app.UIAxes_4.InnerPosition(2);
                    y2 = sum(app.UIAxes_4.InnerPosition([2 4]));
                    if x>x1 && x<x2 && y>y1 && y<y2
                        xl = app.UIAxes_4.XLim;
                        yl = app.UIAxes_4.YLim;
                        xax = xl(1)+(xl(2)-xl(1))*(x-x1)/(x2-x1);
                        yax = yl(1)+(yl(2)-yl(1))*(y-y1)/(y2-y1);
                    
                        if(app.boolLastPoint)
                        app.lastPointX = app.Linea4.XData(app.SelectedIndex); %Save Data for Undo and Redo
                        app.lastPointY = app.Linea4.YData(app.SelectedIndex);
                        app.boolLastPoint = false;
                        end

                        app.lastIndex = app.SelectedIndex;

                        app.Linea4.XData(app.SelectedIndex) = xax;
                        app.Linea4.YData(app.SelectedIndex) = yax;
%                     line(app.UIAxes,xax,yax,'marker','o');
                        
                    end
                end
            end

        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~isnan(app.SelectedIndex)
                app.SelectedIndex = nan;
                
                        app.countUndo = app.countUndo + 1;
                        
                        app.RecentPointsX(app.countUndo) = app.lastPointX; %Save Data for Undo and Redo
                        app.RecentPointsY(app.countUndo) = app.lastPointY;
                        app.RecentAxes(app.countUndo) = app.ActualAxes;
                        app.RecentIndex(app.countUndo) = app.lastIndex;
                        app.boolLastPoint = true;

                        
                      switch app.indexPlot

                          case 0
                            app.XTrans(app.lastIndex) = app.Linea.YData(app.lastIndex);
                            aufleitung(app,0,app.ActualAxes);
                          case 1
                            app.YTrans(app.lastIndex) = app.Linea.YData(app.lastIndex);
                            aufleitung(app,1,app.ActualAxes);
                          case 2
                            app.ZTrans(app.lastIndex) = app.Linea.YData(app.lastIndex);
                            aufleitung(app,2,app.ActualAxes);
                          case 3
                            app.XRot(app.lastIndex) = app.Linea.YData(app.lastIndex);  
                            aufleitung(app,3,app.ActualAxes);
                          case 4
                            app.YRot(app.lastIndex) = app.Linea.YData(app.lastIndex);  
                            aufleitung(app,4,app.ActualAxes);
                          case 5
                            app.ZRot(app.lastIndex) = app.Linea.YData(app.lastIndex);  
                            aufleitung(app,5,app.ActualAxes);
                      end


            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            %Reset Graphs to Home View
            resetViewPlot(app);

            %Reset the values when proceeding to a new category
            resetValues(app);

            app.indexPlot = app.indexPlot + 1;

            if(app.indexPlot > 5)
                app.indexPlot = 5;
            end

            switch app.indexPlot %Plot the next category and add callbacks

                case 0
                    
                    app.Linea = plot(app.UIAxes,app.Time, app.XTrans,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XTrans,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "X - Translatorisch";
                case 1
                    
                    app.Linea = plot(app.UIAxes,app.Time, app.YTrans,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YTrans,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Y - Translatorisch";
                case 2
                    app.Linea = plot(app.UIAxes,app.Time, app.ZTrans,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZTrans,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Z - Translatorisch";
                case 3
                    app.Linea = plot(app.UIAxes,app.Time, app.XRot,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XRot,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "X - Rotatorisch";
                case 4
                    app.Linea = plot(app.UIAxes,app.Time, app.YRot,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YRot,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Y - Rotatorisch";
                case 5
                    app.Linea = plot(app.UIAxes,app.Time, app.ZRot,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZRot,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Z - Rotatorisch";
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            %Reset Graphs to Home View
            resetViewPlot(app);

            %Reset the values when proceeding to a new category
            resetValues(app);

            app.indexPlot = app.indexPlot - 1;

            if(app.indexPlot < 0)
                app.indexPlot = 0;
            end

            switch app.indexPlot %Plot the next category and add callbacks

                case 0
                    app.Linea = plot(app.UIAxes,app.Time, app.XTrans,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XTrans,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "X - Translatorisch";
                case 1
                    app.Linea = plot(app.UIAxes,app.Time, app.YTrans,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YTrans,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Y - Translatorisch";
                case 2
                    app.Linea = plot(app.UIAxes,app.Time, app.ZTrans,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZTrans,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Z - Translatorisch";
                case 3
                    app.Linea = plot(app.UIAxes,app.Time, app.XRot,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XRot,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "X - Rotatorisch";
                case 4
                    app.Linea = plot(app.UIAxes,app.Time, app.YRot,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YRot,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Y - Rotatorisch";
                case 5
                    app.Linea = plot(app.UIAxes,app.Time, app.ZRot,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZRot,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Z - Rotatorisch";
            end
            
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)

            switch app.indexPlot % Redo everything by plotting everything again (NEEDS TO BE ADJUSTED IF AUFLEITUNG HAS BEEN IMPLEMENTED)

                case 0
                    app.XTrans = app.XTransStart;
                    app.Linea = plot(app.UIAxes,app.Time, app.XTransStart,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XTransStart,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "X - Translatorisch";
                case 1
                    app.YTrans = app.YTransStart;
                    app.Linea = plot(app.UIAxes,app.Time, app.YTransStart,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YTransStart,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Y - Translatorisch";
                case 2
                    app.ZTrans = app.ZTransStart;
                    app.Linea = plot(app.UIAxes,app.Time, app.ZTransStart,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZTransStart,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Z - Translatorisch";
                case 3
                    app.XRot = app.XRotStart;
                    app.Linea = plot(app.UIAxes,app.Time, app.XRotStart,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.XRotStart,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "X - Rotatorisch";
                case 4
                    app.YRot = app.YRotStart;
                    app.Linea = plot(app.UIAxes,app.Time, app.YRotStart,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.YRotStart,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Y - Rotatorisch";
                case 5
                    app.ZRot = app.ZRotStart;
                    app.Linea = plot(app.UIAxes,app.Time, app.ZRotStart,'-o');
                    app.Linea.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode, true);
                    [sp,geschwindigkeit,beschleunigung,ruck,time] = spline(app.ZRotStart,app.distanceValues,app.Time,false);
                    app.Linea2 = plot(app.UIAxes_2,time, geschwindigkeit,'-o');
                    app.Linea2.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode2, true);
                    app.Linea3 = plot(app.UIAxes_3,time, beschleunigung,'-o');
                    app.Linea3.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode3, true);
                    app.Linea4 = plot(app.UIAxes_4,time, ruck,'-o');
                    app.Linea4.ButtonDownFcn = createCallbackFcn(app, @ActivateMoveMode4, true);
                    app.XTranslatorischLabel.Text = "Z - Rotatorisch";
            end
            
            resetValues(app); %After plotting reset the values for Undo and Redo
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            
            if(app.countUndo > 0) %Access last point and revert back to its position before
                switch app.RecentAxes(app.countUndo)
                    case 0
                        
                        app.Linea.XData(app.RecentIndex(app.countUndo)) = app.RecentPointsX(app.countUndo); % Undo the last change
                        app.Linea.YData(app.RecentIndex(app.countUndo)) = app.RecentPointsY(app.countUndo);
                        app.lastPointY = app.RecentPointsY(app.countUndo);
                        
                        
                        switch app.indexPlot

                          case 0
                            aufleitung(app,0,app.RecentAxes(app.countUndo));
                          case 1
                            aufleitung(app,1,app.RecentAxes(app.countUndo));
                          case 2
                            aufleitung(app,2,app.RecentAxes(app.countUndo));
                          case 3
                            aufleitung(app,3,app.RecentAxes(app.countUndo));
                          case 4
                            aufleitung(app,4,app.RecentAxes(app.countUndo));
                          case 5
                            aufleitung(app,5,app.RecentAxes(app.countUndo));
                        end


                        app.RecentPointsX(app.countUndo) = []; %Reset some Values
                        app.RecentPointsY(app.countUndo) = [];
                        app.RecentIndex(app.countUndo) = [];
                        app.RecentAxes(app.countUndo) = [];

                        app.countUndo = app.countUndo - 1;
                    case 1
                        app.Linea2.XData(app.RecentIndex(app.countUndo)) = app.RecentPointsX(app.countUndo);
                        app.Linea2.YData(app.RecentIndex(app.countUndo)) = app.RecentPointsY(app.countUndo);

                        switch app.indexPlot

                          case 0
                            aufleitung(app,0,app.RecentAxes(app.countUndo));
                          case 1
                            aufleitung(app,1,app.RecentAxes(app.countUndo));
                          case 2
                            aufleitung(app,2,app.RecentAxes(app.countUndo));
                          case 3
                            aufleitung(app,3,app.RecentAxes(app.countUndo));
                          case 4
                            aufleitung(app,4,app.RecentAxes(app.countUndo));
                          case 5
                            aufleitung(app,5,app.RecentAxes(app.countUndo));
                        end

                        app.RecentPointsX(app.countUndo) = [];
                        app.RecentPointsY(app.countUndo) = [];
                        app.RecentIndex(app.countUndo) = [];
                        app.RecentAxes(app.countUndo) = [];

                        app.countUndo = app.countUndo - 1;
                          
                    case 2
                        app.Linea3.XData(app.RecentIndex(app.countUndo)) = app.RecentPointsX(app.countUndo);
                        app.Linea3.YData(app.RecentIndex(app.countUndo)) = app.RecentPointsY(app.countUndo);

                        switch app.indexPlot

                          case 0
                            aufleitung(app,0,app.RecentAxes(app.countUndo));
                          case 1
                            aufleitung(app,1,app.RecentAxes(app.countUndo));
                          case 2
                            aufleitung(app,2,app.RecentAxes(app.countUndo));
                          case 3
                            aufleitung(app,3,app.RecentAxes(app.countUndo));
                          case 4
                            aufleitung(app,4,app.RecentAxes(app.countUndo));
                          case 5
                            aufleitung(app,5,app.RecentAxes(app.countUndo));
                        end

                        app.RecentPointsX(app.countUndo) = [];
                        app.RecentPointsY(app.countUndo) = [];
                        app.RecentIndex(app.countUndo) = [];
                        app.RecentAxes(app.countUndo) = [];

                        app.countUndo = app.countUndo - 1;
                    
                    case 3
                        app.Linea4.XData(app.RecentIndex(app.countUndo)) = app.RecentPointsX(app.countUndo);
                        app.Linea4.YData(app.RecentIndex(app.countUndo)) = app.RecentPointsY(app.countUndo);

                       switch app.indexPlot

                          case 0
                            aufleitung(app,0,app.RecentAxes(app.countUndo));
                          case 1
                            aufleitung(app,1,app.RecentAxes(app.countUndo));
                          case 2
                            aufleitung(app,2,app.RecentAxes(app.countUndo));
                          case 3
                            aufleitung(app,3,app.RecentAxes(app.countUndo));
                          case 4
                            aufleitung(app,4,app.RecentAxes(app.countUndo));
                          case 5
                            aufleitung(app,5,app.RecentAxes(app.countUndo));
                        end

                        app.RecentPointsX(app.countUndo) = [];
                        app.RecentPointsY(app.countUndo) = [];
                        app.RecentIndex(app.countUndo) = [];
                        app.RecentAxes(app.countUndo) = [];

                        app.countUndo = app.countUndo - 1;

                end
            end
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            EMIFile = app.headerEMI;
            line = "";
            
            for i = 1:length(app.XTrans)
                
                
                signXTrans = num2str(app.XTrans(i),'%.6f');
                signYTrans = num2str(app.YTrans(i),'%.6f');
                signZTrans = num2str(app.ZTrans(i),'%.6f');
                signXRot = num2str(app.XRot(i),'%.6f');
                signYRot = num2str(app.YRot(i),'%.6f');
                signZRot = num2str(app.ZRot(i),'%.6f');
                eigthRow = num2str(app.eighthRow(i));
                ninthRow = num2str(app.ninthRow(i));
                
                if(app.XTrans(i) >= 0)
                
                    signXTrans = append("+",signXTrans);

                end

                if(app.YTrans(i) >= 0)
                
                    signYTrans = append("+",signYTrans);

                end

                if(app.ZTrans(i) >= 0)
                
                    signZTrans = append("+",signZTrans);

                end

                if(app.XRot(i) >= 0)
                
                    signXRot = append("+",signXRot);

                end

                if(app.YRot(i) >= 0)
                
                    signYRot = append("+",signYRot);

                end

                if(app.ZRot(i) >= 0)
                
                    signZRot = append("+",signZRot);

                end

                if(app.eighthRow(i) >= 0)
                
                    eigthRow = append("+",eigthRow);

                end

                if(app.ninthRow(i) >= 0)
                
                    ninthRow = append("+",ninthRow);

                end


                line = append('+',num2str(app.Time(i),'%.6f'),"  ",signXTrans," ",signYTrans," ", signZTrans, " ",signXRot," ",signYRot," ",signZRot," ",eigthRow," ",ninthRow);
                EMIFile = strvcat(EMIFile,line);
            end
            
            EMIFile = strvcat(EMIFile,"[END]");


            [file,path] = uiputfile('Emily.txt');
            if(path ~= 0)
            path = append(path,file);
            fileID = fopen(path,'wt');
            EMI = cellstr(EMIFile);
            for rows = 1:size(EMI,1)
            fprintf(fileID,'%s\n',EMI{rows,:});
            end
            fclose(fileID)
            end
          
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Weg')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Geschwindigkeit')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Beschleunigung')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Ruck')
            xlabel(app.UIAxes_4, 'Time')
            ylabel(app.UIAxes_4, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icons8-pfeil_-lang,-links-64.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'icons8-pfeil_-lang,-rechts-64.png';
            app.Button_2.Text = '';
            app.RedoButton = uibutton(app.UIFigure, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Icon = 'icons8-buchung-64.png';
            app.RedoButton.Text = {'Redo'; ''; ''};
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Icon = 'icons8-wiederkehrender-termin-64.png';
            app.UndoButton.Text = 'Undo';
            app.XTranslatorischLabel = uilabel(app.UIFigure);
            app.XTranslatorischLabel.Text = 'X - Translatorisch';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Icon = 'settings-gears.png';
            app.GenerateButton.Text = {'Generate'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Nachbearbeitung_OptimalControl(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end